classdef Projekt_1_Uhlir_221208 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Layer1neuronsEditField       matlab.ui.control.NumericEditField
        Layer1neuronsEditFieldLabel  matlab.ui.control.Label
        AproximateButton             matlab.ui.control.Button
        Aproximation                 matlab.ui.control.UIAxes
        Shubert                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        xt
        yt
        zt
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            clc;

            app.Shubert.reset
            app.Aproximation.reset

            range = 4;
            conts = 1000;
            xs = linspace(-range, range);
            ys = linspace(-range, range);
            [Xs, Ys] = meshgrid(xs, ys);

            app.xt = Xs;
            app.yt = Ys;

            f = @(xx) shubert(xx);

            for i = 1:100
                for j = 1:100
                    Zs(i, j) = f([Xs(i,j),Ys(i,j)]);
                end
            end
            app.zt = Zs;
            surfc(app.Shubert,Xs,Ys,Zs);

            
        end

        % Button pushed function: AproximateButton
        function AproximateButtonPushed(app, event)
            
            x = reshape(app.xt, 1, []);
            size(x)
            y = reshape(app.yt, 1, []);
            size(y)
            t = reshape(app.zt, 1, []);
            size(t)

            train_set = [x; y]

            ln1 = app.Layer1neuronsEditField.Value;
            
            net = fitnet(ln1);

            net.divideParam.trainRatio = 70/100;
            net.divideParam.valRatio = 15/100;
            net.divideParam.testRatio = 15/100;

            [net, tr] = train(net, train_set, t);

            z = net(train_set);
            e = gsubtract(t,z);
            perfomance = perform(net, t, z)
            
            z = reshape(z, 100, 100);

            surfc(app.Aproximation,app.xt,app.yt,z);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Shubert = uiaxes(app.UIFigure);
            title(app.Shubert, 'Shubert Function')
            xlabel(app.Shubert, 'X')
            ylabel(app.Shubert, 'Y')
            zlabel(app.Shubert, 'Z')
            app.Aproximation = uiaxes(app.UIFigure);
            title(app.Aproximation, 'Aproximation')
            xlabel(app.Aproximation, 'X')
            ylabel(app.Aproximation, 'Y')
            zlabel(app.Aproximation, 'Z')
            app.AproximateButton = uibutton(app.UIFigure, 'push');
            app.AproximateButton.ButtonPushedFcn = createCallbackFcn(app, @AproximateButtonPushed, true);
            app.AproximateButton.Text = 'Aproximate';
            app.Layer1neuronsEditFieldLabel = uilabel(app.UIFigure);
            app.Layer1neuronsEditFieldLabel.Text = 'Layer 1 neurons';
            app.Layer1neuronsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Layer1neuronsEditField.Value = 128;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Projekt_1_Uhlir_221208

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end